classdef OMR_Sheet_Evaluator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OMRSheetEvaluatorUIFigure   matlab.ui.Figure
        ResultPanel                 matlab.ui.container.Panel
        Score_resultPanel           matlab.ui.control.NumericEditField
        Remarks                     matlab.ui.control.EditField
        SubCode_resultPanel         matlab.ui.control.NumericEditField
        Roll_resultPanel            matlab.ui.control.NumericEditField
        RemarksLabel                matlab.ui.control.Label
        ScoreLabel                  matlab.ui.control.Label
        SubjectCodeLabel_2          matlab.ui.control.Label
        RollLabel                   matlab.ui.control.Label
        InsertOMROptionsPanel       matlab.ui.container.Panel
        SubmitButton_2              matlab.ui.control.Button
        InsertOMRDropDown           matlab.ui.control.DropDown
        InsertOMRDropDownLabel      matlab.ui.control.Label
        InsertButton                matlab.ui.control.Button
        RequiredInformationsPanel   matlab.ui.container.Panel
        SubmitButton                matlab.ui.control.Button
        InsertSolutionOMRButton     matlab.ui.control.Button
        ScoreRequiredtoPassLabel    matlab.ui.control.Label
        Score2Pass                  matlab.ui.control.NumericEditField
        SubCode                     matlab.ui.control.NumericEditField
        SubjectCodeLabel            matlab.ui.control.Label
        NumberOfQuestionsLabel      matlab.ui.control.Label
        NumberOfQuestions           matlab.ui.control.NumericEditField
        ResultSummaryPanel          matlab.ui.container.Panel
        PassedStudent               matlab.ui.control.NumericEditField
        PassedStudentsLabel         matlab.ui.control.Label
        HighestScore                matlab.ui.control.NumericEditField
        HighestScoreLabel           matlab.ui.control.Label
        AverageScore                matlab.ui.control.NumericEditField
        AverageScoreLabel           matlab.ui.control.Label
        TotalReject                 matlab.ui.control.NumericEditField
        RejectedAnswerScriptsLabel  matlab.ui.control.Label
        TotalEvaluated              matlab.ui.control.NumericEditField
        TotalEvaluatedAnswerScriptsLabel  matlab.ui.control.Label
        ResultDetailsPanel          matlab.ui.container.Panel
        UITable                     matlab.ui.control.Table
        HelpButton                  matlab.ui.control.Button
        MessagePanel                matlab.ui.container.Panel
        messageArea                 matlab.ui.control.TextArea
        InsertedOMRSheetPanel       matlab.ui.container.Panel
        Image2                      matlab.ui.control.Image
        CloseButton                 matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        EvaluateButton              matlab.ui.control.Button
        InsertOMRButton             matlab.ui.control.Button
        InsertInformationButton     matlab.ui.control.Button
        OMRSheetEvaluatorAppLabel   matlab.ui.control.Label
        Image                       matlab.ui.control.Image
    end

    
    properties (Access = private)
        soln
        n_question
        sub_code
        s2pass %score to pass
        flag_single_omr
        flag_multiple_omr
        single_p_img
        multiple_omr_dir
        flag_insert_soln
        flag_req_info
        index_excel_sheets
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.flag_single_omr=0;
        app.flag_multiple_omr=0;
        app.flag_insert_soln=0;
        app.flag_req_info=0;
        app.index_excel_sheets=0;
        app.messageArea.Value="Welcome to OMR Sheet Evaluator App!!!" + ...
            " You may take help from 'help' boutton to use this app.";
        end

        % Button pushed function: InsertInformationButton
        function InsertInformationButtonPushed(app, event)
            app.RequiredInformationsPanel.Visible=true;
            app.ResultPanel.Visible=false;
            app.InsertedOMRSheetPanel.Visible=false;
            app.ResultDetailsPanel.Visible=false;
            app.ResultSummaryPanel.Visible=false;
            app.InsertOMROptionsPanel.Visible=false;
            app.ResultDetailsPanel.Visible=false;
        end

        % Button pushed function: InsertSolutionOMRButton
        function InsertSolutionOMRButtonPushed(app, event)
             i=uigetfile('*.png;*.jpg;*.jpeg','Select the Solution OMR Sheet');
             im=imread(i);
             p_img=img_processed(im);
             app.soln=get_soln(p_img,app.NumberOfQuestions.Value);
             app.flag_insert_soln=1;
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
           if ( app.NumberOfQuestions.Value==0 ...
                   ||app.Score2Pass.Value==0||app.SubCode.Value==0 || app.flag_insert_soln==0 )
               app.messageArea.Value="Please, fill up all the required " + ...
                   "informations properly."
           else
           app.n_question=app.NumberOfQuestions.Value;
           app.s2pass=app.Score2Pass.Value;
           app.sub_code=app.SubCode.Value;
           app.RequiredInformationsPanel.Visible=false;
           app.flag_req_info=1;
           app.messageArea.Value="You updated required information" + ...
               " successfully. Now, you can insert OMR to evaluate.";
           end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: InsertOMRButton
        function InsertOMRButtonPushed(app, event)
            if (app.flag_req_info==1 && app.flag_insert_soln==1 )
                app.InsertOMROptionsPanel.Visible=true;
                app.RequiredInformationsPanel.Visible=false;
                app.ResultPanel.Visible=false;
                app.InsertedOMRSheetPanel.Visible=false;
                app.ResultDetailsPanel.Visible=false;
                app.ResultSummaryPanel.Visible=false;
                app.ResultDetailsPanel.Visible=false;
                app.messageArea.Value="Now, insert OMR to evaluate from the Insert OMR option panel." + ...
                    " You can evaluate a single OMR sheet or a set of OMR sheets.";
            else 
                app.messageArea.Value="Firstly, you have to fill up required information properly.";
            end
        end

        % Button pushed function: InsertButton
        function InsertButtonPushed(app, event)
            if strcmp(app.InsertOMRDropDown.Value,"Single")
                i=uigetfile('*.png;*.jpg;*.jpeg','Select an OMR sheet to evaluate');
                im=imread(i);
                app.Image2.ImageSource=i; % shows the inserted image
                app.single_p_img=img_processed(im); % processed image using function
                app.flag_single_omr=1;
                app.flag_multiple_omr=0;
                app.messageArea.Value="You inserted an OMR sheet successfully. " + ...
                    "Click evauate button to get result.";
            else
                f=uigetdir();
                app.multiple_omr_dir=f;
                app.flag_multiple_omr=1;
                app.flag_single_omr=0;
                app.messageArea.Value="You inserted multipe OMR sheets successfully. " + ...
                    "Click evauate button to get result."
            end
        end

        % Button pushed function: SubmitButton_2
        function SubmitButton_2Pushed(app, event)
            if app.flag_single_omr==1
                app.InsertedOMRSheetPanel.Visible=true;
            else
                app.InsertedOMRSheetPanel.Visible=false;
            end
            app.InsertOMROptionsPanel.Visible=false;
            
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            if app.flag_single_omr==1
                app.ResultPanel.Visible=true;
                app.InsertedOMRSheetPanel.Visible=true;
                app.RequiredInformationsPanel.Visible=false;
                app.ResultDetailsPanel.Visible=false;
                app.ResultSummaryPanel.Visible=false;
                app.InsertOMROptionsPanel.Visible=false;
                app.ResultDetailsPanel.Visible=false;
                [roll,roll_valid]=get_roll(app.single_p_img);
                app.Roll_resultPanel.Value=roll; 
                  %  getting roll from get_roll function.
                app.Score_resultPanel.Value=evaluate_single_omr(app.single_p_img,app.soln,app.n_question);
                    % geting score from evaluate_single_omr function
                [code,code_valid]=get_code(app.single_p_img);
                app.SubCode_resultPanel.Value=code;
                    %  getting code from get_code function.
                score= app.Score_resultPanel.Value;

                if score < app.Score2Pass.Value
                    app.Remarks.Value="Fail!";
                elseif score >= app.Score2Pass.Value
                    app.Remarks.Value="Pass!";
                end
                
                if (code_valid==0)
                    app.Remarks.Value="code is invalid";
                elseif (roll_valid==0)
                    app.Remarks.Value="Roll is invalid";
                elseif app.SubCode.Value~=app.SubCode_resultPanel.Value
                    app.Remarks.Value="SubCode Mismatched";
                end
                app.messageArea.Value="The inserted OMR sheet is evaluated Successfully!";
            elseif app.flag_multiple_omr==1
                f=app.multiple_omr_dir;
                [roll_array,code_array,marks_array,reject]=evaluate_multiple_omr ...
                    (f,app.soln,app.n_question,app.SubCode.Value);
                app.ResultDetailsPanel.Visible=true;
                app.ResultPanel.Visible=false;
                app.InsertedOMRSheetPanel.Visible=false;
                app.RequiredInformationsPanel.Visible=false;
                app.InsertOMROptionsPanel.Visible=false;
                roll_str=num2str(roll_array);
                [row,col]=size(roll_str);
                high=0;
                total_score=0;
                passed=0;
                for del_ind=1:10
                app.UITable.Data{del_ind,1}=[];
                app.UITable.Data{del_ind,2}=[];
                end
                 for index=1:row % indexing all the evaluated omr informations
                    app.UITable.Data{index,1} = roll_str(index,:); 
                            %inerting roll string in the 1st row of the table 
                    app.UITable.Data{index,2} = marks_array(index);
                            %inerting score in the 2nd row of the table 
                    total_score=total_score+marks_array(index);
                    if marks_array(index)>high
                        high=marks_array(index);
                    end
                    if marks_array(index)>=app.Score2Pass.Value
                        passed=passed+1;
                    end
                 end
                if row~=0
                xlswrite('result.xls',cell(30,1),'Sheet1','A2:A100');
                xlswrite('result.xls',cell(30,1),'Sheet1','B2:B100');
                xlswrite('result.xls',cell(30,1),'Sheet1','C2:C100');
                xlswrite('result.xls',roll_array,'Sheet1','A2');
                xlswrite('result.xls',code_array,'Sheet1','B2');
                xlswrite('result.xls',marks_array,'Sheet1','C2');
                end
               
                app.ResultSummaryPanel.Visible=true;
                app.TotalEvaluated.Value=row;
                app.TotalReject.Value=reject;
                if row~=0
                    app.AverageScore.Value=total_score/row;
                else 
                    app.AverageScore.Value=0;
                end
                app.HighestScore.Value=high;
                app.PassedStudent.Value=passed;
                app.messageArea.Value="The inserted OMR sheets are evaluated Successfully!";
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
                flag_single_omr=0;
                flag_multiple_omr=0;
                flag_insert_soln=0;
                flag_req_info=0;
                app.InsertOMROptionsPanel.Visible=false;
                app.RequiredInformationsPanel.Visible=false;
                app.ResultPanel.Visible=false;
                app.InsertedOMRSheetPanel.Visible=false;
                app.ResultDetailsPanel.Visible=false;
                app.ResultSummaryPanel.Visible=false;
                app.ResultDetailsPanel.Visible=false;
                app.messageArea.Value=" ";
                app.Score2Pass.Value=0;
                app.NumberOfQuestions.Value=0;
                app.SubCode.Value=0;
                app.soln=[];
                app.n_question=0;
                app.sub_code=0;
                app.s2pass =0;
                app.single_p_img=[];
                app.multiple_omr_dir=0;
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.messageArea.Value="First, insert necessary information from the 'Insert Information' button." + ...
                "Then input ans script/s from 'Insert OMR'. Then click 'Evaluate' button for result.";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.OMRSheetEvaluatorUIFigure = uifigure('Visible', 'off');
            colormap(app.OMRSheetEvaluatorUIFigure, 'parula');
            app.OMRSheetEvaluatorUIFigure.Name = 'OMR Sheet Evaluator';
            app.Image = uiimage(app.OMRSheetEvaluatorUIFigure);
            app.Image.Enable = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'background2.jpg');
            app.OMRSheetEvaluatorAppLabel = uilabel(app.OMRSheetEvaluatorUIFigure);
            app.OMRSheetEvaluatorAppLabel.Text = 'OMR Sheet Evaluator App';
            app.InsertInformationButton = uibutton(app.OMRSheetEvaluatorUIFigure, 'push');
            app.InsertInformationButton.ButtonPushedFcn = createCallbackFcn(app, @InsertInformationButtonPushed, true);
            app.InsertInformationButton.Text = 'Insert Information';
            app.InsertOMRButton = uibutton(app.OMRSheetEvaluatorUIFigure, 'push');
            app.InsertOMRButton.ButtonPushedFcn = createCallbackFcn(app, @InsertOMRButtonPushed, true);
            app.InsertOMRButton.Text = 'Insert OMR';
            app.EvaluateButton = uibutton(app.OMRSheetEvaluatorUIFigure, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.ResetButton = uibutton(app.OMRSheetEvaluatorUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CloseButton = uibutton(app.OMRSheetEvaluatorUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.InsertedOMRSheetPanel = uipanel(app.OMRSheetEvaluatorUIFigure);
            app.InsertedOMRSheetPanel.Title = 'Inserted OMR Sheet';
            app.InsertedOMRSheetPanel.Visible = 'off';
            app.Image2 = uiimage(app.InsertedOMRSheetPanel);
            app.MessagePanel = uipanel(app.OMRSheetEvaluatorUIFigure);
            app.MessagePanel.Title = 'Message';
            app.messageArea = uitextarea(app.MessagePanel);
            app.HelpButton = uibutton(app.OMRSheetEvaluatorUIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.ResultDetailsPanel = uipanel(app.OMRSheetEvaluatorUIFigure);
            app.ResultDetailsPanel.Title = 'Result Details';
            app.ResultDetailsPanel.Visible = 'off';
            app.UITable = uitable(app.ResultDetailsPanel);
            app.UITable.ColumnName = {'Roll'; 'Score'; ''; ''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true false false];
            app.ResultSummaryPanel = uipanel(app.OMRSheetEvaluatorUIFigure);
            app.ResultSummaryPanel.Title = 'Result Summary';
            app.ResultSummaryPanel.Visible = 'off';
            app.TotalEvaluatedAnswerScriptsLabel = uilabel(app.ResultSummaryPanel);
            app.TotalEvaluatedAnswerScriptsLabel.Text = 'Total  Evaluated Answer Scripts';
            app.TotalEvaluated = uieditfield(app.ResultSummaryPanel, 'numeric');
            app.TotalEvaluated.Limits = [0 9999999999];
            app.TotalEvaluated.ValueDisplayFormat = '%.0f';
            app.RejectedAnswerScriptsLabel = uilabel(app.ResultSummaryPanel);
            app.RejectedAnswerScriptsLabel.Text = 'Rejected Answer Scripts';
            app.TotalReject = uieditfield(app.ResultSummaryPanel, 'numeric');
            app.TotalReject.Limits = [0 9999999999];
            app.TotalReject.ValueDisplayFormat = '%.0f';
            app.AverageScoreLabel = uilabel(app.ResultSummaryPanel);
            app.AverageScoreLabel.Text = 'Average Score';
            app.AverageScore = uieditfield(app.ResultSummaryPanel, 'numeric');
            app.AverageScore.Limits = [0 9999999999];
            app.AverageScore.ValueDisplayFormat = '%.0f';
            app.HighestScoreLabel = uilabel(app.ResultSummaryPanel);
            app.HighestScoreLabel.Text = 'Highest Score';
            app.HighestScore = uieditfield(app.ResultSummaryPanel, 'numeric');
            app.HighestScore.Limits = [0 9999999999];
            app.HighestScore.ValueDisplayFormat = '%.0f';
            app.PassedStudentsLabel = uilabel(app.ResultSummaryPanel);
            app.PassedStudentsLabel.Text = 'Passed Students';
            app.PassedStudent = uieditfield(app.ResultSummaryPanel, 'numeric');
            app.PassedStudent.Limits = [0 9999999999];
            app.PassedStudent.ValueDisplayFormat = '%.0f';
            app.RequiredInformationsPanel = uipanel(app.OMRSheetEvaluatorUIFigure);
            app.RequiredInformationsPanel.Title = 'Required Informations';
            app.RequiredInformationsPanel.Visible = 'off';
            app.NumberOfQuestions = uieditfield(app.RequiredInformationsPanel, 'numeric');
            app.NumberOfQuestions.Limits = [0 50];
            app.NumberOfQuestions.ValueDisplayFormat = '%.0f';
            app.NumberOfQuestionsLabel = uilabel(app.RequiredInformationsPanel);
            app.NumberOfQuestionsLabel.Text = 'Number Of Questions';
            app.SubjectCodeLabel = uilabel(app.RequiredInformationsPanel);
            app.SubjectCodeLabel.Text = 'Subject Code';
            app.SubCode = uieditfield(app.RequiredInformationsPanel, 'numeric');
            app.SubCode.Limits = [0 999];
            app.SubCode.ValueDisplayFormat = '%.0f';
            app.Score2Pass = uieditfield(app.RequiredInformationsPanel, 'numeric');
            app.Score2Pass.Limits = [0 50];
            app.Score2Pass.ValueDisplayFormat = '%.0f';
            app.ScoreRequiredtoPassLabel = uilabel(app.RequiredInformationsPanel);
            app.ScoreRequiredtoPassLabel.Text = 'Score Required to Pass';
            app.InsertSolutionOMRButton = uibutton(app.RequiredInformationsPanel, 'push');
            app.InsertSolutionOMRButton.ButtonPushedFcn = createCallbackFcn(app, @InsertSolutionOMRButtonPushed, true);
            app.InsertSolutionOMRButton.Text = 'Insert Solution OMR';
            app.SubmitButton = uibutton(app.RequiredInformationsPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.InsertOMROptionsPanel = uipanel(app.OMRSheetEvaluatorUIFigure);
            app.InsertOMROptionsPanel.Title = 'Insert OMR Options';
            app.InsertOMROptionsPanel.Visible = 'off';
            app.InsertButton = uibutton(app.InsertOMROptionsPanel, 'push');
            app.InsertButton.ButtonPushedFcn = createCallbackFcn(app, @InsertButtonPushed, true);
            app.InsertButton.Text = 'Insert';
            app.InsertOMRDropDownLabel = uilabel(app.InsertOMROptionsPanel);
            app.InsertOMRDropDownLabel.Text = 'Insert OMR';
            app.InsertOMRDropDown = uidropdown(app.InsertOMROptionsPanel);
            app.InsertOMRDropDown.Items = {'Single', 'Multiple'};
            app.InsertOMRDropDown.Value = 'Single';
            app.SubmitButton_2 = uibutton(app.InsertOMROptionsPanel, 'push');
            app.SubmitButton_2.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_2Pushed, true);
            app.SubmitButton_2.Text = 'Submit';
            app.ResultPanel = uipanel(app.OMRSheetEvaluatorUIFigure);
            app.ResultPanel.Title = 'Result';
            app.ResultPanel.Visible = 'off';
            app.RollLabel = uilabel(app.ResultPanel);
            app.RollLabel.Text = 'Roll';
            app.SubjectCodeLabel_2 = uilabel(app.ResultPanel);
            app.SubjectCodeLabel_2.Text = 'Subject Code';
            app.ScoreLabel = uilabel(app.ResultPanel);
            app.ScoreLabel.Text = 'Score';
            app.RemarksLabel = uilabel(app.ResultPanel);
            app.RemarksLabel.Text = 'Remarks';
            app.Roll_resultPanel = uieditfield(app.ResultPanel, 'numeric');
            app.Roll_resultPanel.Limits = [0 9999999999];
            app.Roll_resultPanel.ValueDisplayFormat = '%.0f';
            app.SubCode_resultPanel = uieditfield(app.ResultPanel, 'numeric');
            app.SubCode_resultPanel.Limits = [0 9999999999];
            app.SubCode_resultPanel.ValueDisplayFormat = '%.0f';
            app.Remarks = uieditfield(app.ResultPanel, 'text');
            app.Score_resultPanel = uieditfield(app.ResultPanel, 'numeric');
            app.Score_resultPanel.Limits = [0 9999999999];
            app.Score_resultPanel.ValueDisplayFormat = '%.0f';
            % Show the figure after all components are created
            app.OMRSheetEvaluatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OMR_Sheet_Evaluator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OMRSheetEvaluatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OMRSheetEvaluatorUIFigure)
        end
    end
end